classdef Tugas1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ImagegraylevelhistogramTab    matlab.ui.container.Tab
        LoadimageButtonGrayHist       matlab.ui.control.Button
        ExampleimageGrayHist          matlab.ui.control.DropDown
        ExampleimageDropDownLabel     matlab.ui.control.Label
        InputGrayHist                 matlab.ui.control.UIAxes
        HistGrayHist                  matlab.ui.control.UIAxes
        ContrastEnchancementTab       matlab.ui.container.Tab
        ExampleImageContEnchancement  matlab.ui.control.DropDown
        ExampleImageDropDownLabel_2   matlab.ui.control.Label
        LoadImageButtonContEnchancement  matlab.ui.control.Button
        InputLabel_2                  matlab.ui.control.Label
        ResultLabel_2                 matlab.ui.control.Label
        OutputContEnchance            matlab.ui.control.UIAxes
        InputContEnchance             matlab.ui.control.UIAxes
        BlueAxesInput_4               matlab.ui.control.UIAxes
        RedAxesInput_4                matlab.ui.control.UIAxes
        GreenAxesInput_4              matlab.ui.control.UIAxes
        BlueAxesInput_3               matlab.ui.control.UIAxes
        RedAxesInput_3                matlab.ui.control.UIAxes
        GreenAxesInput_3              matlab.ui.control.UIAxes
        HistogramEqualizationTab      matlab.ui.container.Tab
        LoadImageButton               matlab.ui.control.Button
        ExampleImageHisteq            matlab.ui.control.DropDown
        ExampleImageDropDownLabel     matlab.ui.control.Label
        InputLabel                    matlab.ui.control.Label
        ResultLabel                   matlab.ui.control.Label
        BlueAxesInput_2               matlab.ui.control.UIAxes
        RedAxesInput_2                matlab.ui.control.UIAxes
        GreenAxesInput_2              matlab.ui.control.UIAxes
        ImageInput_2                  matlab.ui.control.UIAxes
        BlueAxesInput                 matlab.ui.control.UIAxes
        RedAxesInput                  matlab.ui.control.UIAxes
        GreenAxesInput                matlab.ui.control.UIAxes
        ImageInput                    matlab.ui.control.UIAxes
        HistogramSpecificationTab     matlab.ui.container.Tab
    end

    % Methid for image histogram equalization
    methods (Access = private)
        % Histogram Equalization function helper
        function loadImageHisteq(app, imagefile)
            try
                im = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            updateInputHisteq(app, im); 
            switch size(im,3)
                case 1
                    imHisteq = histeq(app,im);
                case 3
                    imHisteq(:,:,1) = histeq(app,im(:,:,1));
                    imHisteq(:,:,2) = histeq(app,im(:,:,2));
                    imHisteq(:,:,3) = histeq(app,im(:,:,3));
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
            disp(size(im))
            disp(size(imHisteq))
            updateOutputHisteq(app,imHisteq)
        end

        function updateInputHisteq(app, im)
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageInput,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxesInput, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxesInput, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxesInput, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageInput,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxesInput, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxesInput, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxesInput, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxesInput.YLim = [0 maxcount];
                app.RedAxesInput.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxesInput.YLim = [0 maxcount];
                app.GreenAxesInput.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxesInput.YLim = [0 maxcount];
                app.BlueAxesInput.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
        end

        function updateOutputHisteq(app, im)
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageInput_2,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxesInput_2, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxesInput_2, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxesInput_2, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageInput_2,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxesInput_2, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxesInput_2, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxesInput_2, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxesInput_2.YLim = [0 maxcount];
                app.RedAxesInput_2.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxesInput_2.YLim = [0 maxcount];
                app.GreenAxesInput_2.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxesInput_2.YLim = [0 maxcount];
                app.BlueAxesInput_2.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
        end

        function imResult = histeq(~, im)
            numofpixels = size(im,1)*size(im,2);
            imResult = uint8(zeros(size(im)));
            histEq = zeros(256);
            histFreq = zeros(256);
            histProb = zeros(256);
            
            for i = 1:size(im,1)
                for j = 1:size(im,2)
                    greyLevel = im(i,j);
                    histFreq(greyLevel+1)=histFreq(greyLevel+1)+1;
                    histProb(greyLevel+1)=histFreq(greyLevel+1)/numofpixels;
                end
            end

            sum = 0.0;
            for i = 1:256
                sum = sum + histFreq(i);
                histEq(i) = floor((sum/numofpixels)*255);
            end

            disp(histEq)

            imSize = size(im);
            for i = 1:imSize(1)
                for j = 1:imSize(2)
                    imResult(i,j) = histEq(im(i,j)+1);
                end
            end
        end
        
        % ======= Functions for image grayscale histogram =======
        function image = loadImageGrayHist(app, imagefile)
            try
                image = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            % Error when image is not grayscale or truecolor
            if size(image, 3) ~= 3 && size(image, 3) ~= 1
                uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                return;
            end
            
            imagesc(app.InputGrayHist, image);
            histogram(app.HistGrayHist, image, 256);
        end
        
        function vect = matToVect(~, mat)
            vect = reshape(mat, [], 1);
            vect = reshape(vect, 1, []);
        end
        % ======= End of functions for image grayscale histogram =======
        
        % ======= Functions for contrast enchancement =======
        function enchantedImage = contrastEnchancement(app, originalImage)
            if size(originalImage, 3) == 1
                grayImage = originalImage;
            elseif size(originalImage, 3) == 3
                grayImage = rgb2gray(originalImage);
            else
                return
            end
                
            %find r_min and r_max
            r_min = min(matToVect(app, grayImage));
            r_max = max(matToVect(app, grayImage));
            
            n_row = size(originalImage, 1);
            n_col = size(originalImage , 2);
            n_dim = size(originalImage , 3);
            enchantedImage = zeros(n_row, n_col);
            for i = 1:n_row
                for j = 1:n_col
                    for k = 1:n_dim
                        pixelValue = originalImage(i, j, k);
                        enchantedImage(i, j, k) = ceil((double(pixelValue-r_min)/double(r_max-r_min))*double(255));
                    end
                end
            end
            
            enchantedImage = uint8(enchantedImage);
            updateOutputHistContEnchancement(app, enchantedImage);
        end
        
        function image = loadImageContEnchancement(app, imagefile)
            try
                image = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            % Error when image is not grayscale or truecolor
            if size(image, 3) ~= 3 && size(image, 3) ~= 1
                uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                return;
            end
            updateInputHistContEnchancement(app, image);
            contrastEnchancement(app, image);
        end
        
        function [histr, histg, histb] = updateInputHistContEnchancement(app, im)
            imagesc(app.InputContEnchance, im);
            switch size(im,3)
                case 1
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxesInput_3, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxesInput_3, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxesInput_3, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Plot the histograms
                    histr = histogram(app.RedAxesInput_3, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxesInput_3, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxesInput_3, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
        end
        
        function [histr, histg, histb] = updateOutputHistContEnchancement(app, im)
            imagesc(app.OutputContEnchance, im);
            switch size(im,3)
                case 1
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxesInput_4, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxesInput_4, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxesInput_4, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Plot the histograms
                    histr = histogram(app.RedAxesInput_4, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxesInput_4, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxesInput_4, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
        end
        
        % ======= End of functions for contrast enchancement =======
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageInput.Visible = 'off';
            app.ImageInput.Colormap = gray(256);
            axis(app.ImageInput, 'image');

            app.ImageInput_2.Visible = 'off';
            app.ImageInput_2.Colormap = gray(256);
            axis(app.ImageInput_2, 'image');

            loadImageHisteq(app, 'citra kontras rendah/Picture1.jpg');
            loadImageGrayHist(app, 'citra kontras rendah/monyet.jpg');
            loadImageContEnchancement(app, 'citra kontras rendah/lembah1.jpg');
        end

        % Value changed function: ExampleImageHisteq
        function ExampleImageHisteqValueChanged(app, event)
            value = app.ExampleImageHisteq.Value;
            switch value
                case 'Kacang'
                    loadImageHisteq(app, 'citra kontras rendah/Picture1.jpg')
                case 'Kota'
                    loadImageHisteq(app, 'citra kontras rendah/Picture2.jpg')
                case 'Jembatan'
                    loadImageHisteq(app, 'citra kontras rendah/Picture3.png')
                case 'Ruangan'
                    loadImageHisteq(app, 'citra kontras rendah/Picture4.jpg')
                case 'Pacar Rahadian'
                    loadImageHisteq(app, 'citra kontras rendah/Picture5.jpg')
                case 'Kucing'
                    loadImageHisteq(app, 'citra kontras rendah/Picture6.jpg')
            end
        end

        % Button pushed function: LoadImageButton
        function LoadImageHisteqButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               loadImageHisteq(app, fname);
            end
        end

        % Value changed function: ExampleimageGrayHist
        function ExampleimageGrayHistValueChanged(app, event)
            value = app.ExampleimageGrayHist.Value;
            switch value
                case 'Monyet'
                    loadImageGrayHist(app, 'citra kontras rendah/monyet.jpg')
                case 'Kota'
                    loadImageGrayHist(app, 'citra kontras rendah/Picture2.jpg')
                case 'Strawbery'
                    loadImageGrayHist(app, 'citra kontras rendah/strawbery.tif')
                case 'Botol'
                    loadImageGrayHist(app, 'citra kontras rendah/botol.tif')
                case 'Awan'
                    loadImageGrayHist(app, 'citra kontras rendah/awan.jpg')
                case 'Wajah'
                    loadImageGrayHist(app, 'citra kontras rendah/wajah.jpg')
            end
        end

        % Value changed function: ExampleImageContEnchancement
        function ExampleImageContEnchancementValueChanged(app, event)
            value = app.ExampleImageContEnchancement.Value;
            switch value
                case 'Lembah 1'
                    loadImageContEnchancement(app, 'citra kontras rendah/lembah1.jpg')
                case 'Lembah 2'
                    loadImageContEnchancement(app, 'citra kontras rendah/lembah2.jpg')
                case 'Kacang'
                    loadImageContEnchancement(app, 'citra kontras rendah/Picture1.jpg')
                case 'Ruangan'
                    loadImageContEnchancement(app, 'citra kontras rendah/Picture4.jpg')
                case 'Awan'
                    loadImageContEnchancement(app, 'citra kontras rendah/awan.jpg')
                case 'Wajah'
                    loadImageContEnchancement(app, 'citra kontras rendah/wajah.jpg')
            end
        end

        % Button pushed function: LoadimageButtonGrayHist
        function LoadimageButtonGrayHistPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               loadImageGrayHist(app, fname);
            end
        end

        % Button pushed function: LoadImageButtonContEnchancement
        function LoadImageButtonContEnchancementPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               loadImageContEnchancement(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImagegraylevelhistogramTab = uitab(app.TabGroup);
            app.ImagegraylevelhistogramTab.Title = 'Image graylevel histogram';
            app.HistGrayHist = uiaxes(app.ImagegraylevelhistogramTab);
            title(app.HistGrayHist, 'Graylevels Histogram')
            xlabel(app.HistGrayHist, 'Graylevels')
            ylabel(app.HistGrayHist, 'Counts')
            zlabel(app.HistGrayHist, 'Z')
            app.InputGrayHist = uiaxes(app.ImagegraylevelhistogramTab);
            app.InputGrayHist.XTick = [];
            app.InputGrayHist.YTick = [];
            app.ExampleimageDropDownLabel = uilabel(app.ImagegraylevelhistogramTab);
            app.ExampleimageDropDownLabel.Text = 'Example image';
            app.ExampleimageGrayHist = uidropdown(app.ImagegraylevelhistogramTab);
            app.ExampleimageGrayHist.Items = {'Monyet', 'Kota', 'Strawbery', 'Botol', 'Awan', 'Wajah', ''};
            app.ExampleimageGrayHist.ValueChangedFcn = createCallbackFcn(app, @ExampleimageGrayHistValueChanged, true);
            app.ExampleimageGrayHist.Value = 'Monyet';
            app.LoadimageButtonGrayHist = uibutton(app.ImagegraylevelhistogramTab, 'push');
            app.LoadimageButtonGrayHist.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonGrayHistPushed, true);
            app.LoadimageButtonGrayHist.Text = 'Load image';
            app.ContrastEnchancementTab = uitab(app.TabGroup);
            app.ContrastEnchancementTab.Title = 'Contrast Enchancement';
            app.GreenAxesInput_3 = uiaxes(app.ContrastEnchancementTab);
            title(app.GreenAxesInput_3, 'Green')
            xlabel(app.GreenAxesInput_3, 'Intensity')
            ylabel(app.GreenAxesInput_3, 'Pixels')
            app.GreenAxesInput_3.XLim = [0 255];
            app.GreenAxesInput_3.XTick = [0 128 255];
            app.RedAxesInput_3 = uiaxes(app.ContrastEnchancementTab);
            title(app.RedAxesInput_3, 'Red')
            xlabel(app.RedAxesInput_3, 'Intensity')
            ylabel(app.RedAxesInput_3, 'Pixels')
            app.RedAxesInput_3.XLim = [0 255];
            app.RedAxesInput_3.XTick = [0 128 255];
            app.BlueAxesInput_3 = uiaxes(app.ContrastEnchancementTab);
            title(app.BlueAxesInput_3, 'Blue')
            xlabel(app.BlueAxesInput_3, 'Intensity')
            ylabel(app.BlueAxesInput_3, 'Pixels')
            app.BlueAxesInput_3.XLim = [0 255];
            app.BlueAxesInput_3.XTick = [0 128 255];
            app.GreenAxesInput_4 = uiaxes(app.ContrastEnchancementTab);
            title(app.GreenAxesInput_4, 'Green')
            xlabel(app.GreenAxesInput_4, 'Intensity')
            ylabel(app.GreenAxesInput_4, 'Pixels')
            app.GreenAxesInput_4.XLim = [0 255];
            app.GreenAxesInput_4.XTick = [0 128 255];
            app.RedAxesInput_4 = uiaxes(app.ContrastEnchancementTab);
            title(app.RedAxesInput_4, 'Red')
            xlabel(app.RedAxesInput_4, 'Intensity')
            ylabel(app.RedAxesInput_4, 'Pixels')
            app.RedAxesInput_4.XLim = [0 255];
            app.RedAxesInput_4.XTick = [0 128 255];
            app.BlueAxesInput_4 = uiaxes(app.ContrastEnchancementTab);
            title(app.BlueAxesInput_4, 'Blue')
            xlabel(app.BlueAxesInput_4, 'Intensity')
            ylabel(app.BlueAxesInput_4, 'Pixels')
            app.BlueAxesInput_4.XLim = [0 255];
            app.BlueAxesInput_4.XTick = [0 128 255];
            app.InputContEnchance = uiaxes(app.ContrastEnchancementTab);
            app.InputContEnchance.XTick = [];
            app.InputContEnchance.XTickLabel = '';
            app.InputContEnchance.YTick = [];
            app.InputContEnchance.YTickLabel = '';
            app.OutputContEnchance = uiaxes(app.ContrastEnchancementTab);
            app.OutputContEnchance.XTick = [];
            app.OutputContEnchance.YTick = [];
            app.ResultLabel_2 = uilabel(app.ContrastEnchancementTab);
            app.ResultLabel_2.Text = 'Result';
            app.InputLabel_2 = uilabel(app.ContrastEnchancementTab);
            app.InputLabel_2.Text = 'Input';
            app.LoadImageButtonContEnchancement = uibutton(app.ContrastEnchancementTab, 'push');
            app.LoadImageButtonContEnchancement.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonContEnchancementPushed, true);
            app.LoadImageButtonContEnchancement.Text = 'Load Image';
            app.ExampleImageDropDownLabel_2 = uilabel(app.ContrastEnchancementTab);
            app.ExampleImageDropDownLabel_2.Text = 'Example Image';
            app.ExampleImageContEnchancement = uidropdown(app.ContrastEnchancementTab);
            app.ExampleImageContEnchancement.Items = {'Lembah 1', 'Lembah 2', 'Kacang', 'Ruangan', 'Awan', 'Wajah'};
            app.ExampleImageContEnchancement.ValueChangedFcn = createCallbackFcn(app, @ExampleImageContEnchancementValueChanged, true);
            app.ExampleImageContEnchancement.Value = 'Lembah 1';
            app.HistogramEqualizationTab = uitab(app.TabGroup);
            app.HistogramEqualizationTab.Title = 'Histogram Equalization';
            app.ImageInput = uiaxes(app.HistogramEqualizationTab);
            app.ImageInput.XLim = [0 1];
            app.ImageInput.XTick = [];
            app.ImageInput.YTick = [];
            app.GreenAxesInput = uiaxes(app.HistogramEqualizationTab);
            title(app.GreenAxesInput, 'Green')
            xlabel(app.GreenAxesInput, 'Intensity')
            ylabel(app.GreenAxesInput, 'Pixels')
            app.GreenAxesInput.XLim = [0 255];
            app.GreenAxesInput.XTick = [0 128 255];
            app.RedAxesInput = uiaxes(app.HistogramEqualizationTab);
            title(app.RedAxesInput, 'Red')
            xlabel(app.RedAxesInput, 'Intensity')
            ylabel(app.RedAxesInput, 'Pixels')
            app.RedAxesInput.XLim = [0 255];
            app.RedAxesInput.XTick = [0 128 255];
            app.BlueAxesInput = uiaxes(app.HistogramEqualizationTab);
            title(app.BlueAxesInput, 'Blue')
            xlabel(app.BlueAxesInput, 'Intensity')
            ylabel(app.BlueAxesInput, 'Pixels')
            app.BlueAxesInput.XLim = [0 255];
            app.BlueAxesInput.XTick = [0 128 255];
            app.ImageInput_2 = uiaxes(app.HistogramEqualizationTab);
            app.ImageInput_2.XLim = [0 1];
            app.ImageInput_2.XTick = [];
            app.ImageInput_2.YTick = [];
            app.GreenAxesInput_2 = uiaxes(app.HistogramEqualizationTab);
            title(app.GreenAxesInput_2, 'Green')
            xlabel(app.GreenAxesInput_2, 'Intensity')
            ylabel(app.GreenAxesInput_2, 'Pixels')
            app.GreenAxesInput_2.XLim = [0 255];
            app.GreenAxesInput_2.XTick = [0 128 255];
            app.RedAxesInput_2 = uiaxes(app.HistogramEqualizationTab);
            title(app.RedAxesInput_2, 'Red')
            xlabel(app.RedAxesInput_2, 'Intensity')
            ylabel(app.RedAxesInput_2, 'Pixels')
            app.RedAxesInput_2.XLim = [0 255];
            app.RedAxesInput_2.XTick = [0 128 255];
            app.BlueAxesInput_2 = uiaxes(app.HistogramEqualizationTab);
            title(app.BlueAxesInput_2, 'Blue')
            xlabel(app.BlueAxesInput_2, 'Intensity')
            ylabel(app.BlueAxesInput_2, 'Pixels')
            app.BlueAxesInput_2.XLim = [0 255];
            app.BlueAxesInput_2.XTick = [0 128 255];
            app.ResultLabel = uilabel(app.HistogramEqualizationTab);
            app.ResultLabel.Text = 'Result';
            app.InputLabel = uilabel(app.HistogramEqualizationTab);
            app.InputLabel.Text = 'Input';
            app.ExampleImageDropDownLabel = uilabel(app.HistogramEqualizationTab);
            app.ExampleImageDropDownLabel.Text = 'Example Image';
            app.ExampleImageHisteq = uidropdown(app.HistogramEqualizationTab);
            app.ExampleImageHisteq.Items = {'Kacang', 'Kota', 'Jembatan', 'Ruangan', 'Pacar Rahadian', 'Kucing'};
            app.ExampleImageHisteq.ValueChangedFcn = createCallbackFcn(app, @ExampleImageHisteqValueChanged, true);
            app.ExampleImageHisteq.Value = 'Kacang';
            app.LoadImageButton = uibutton(app.HistogramEqualizationTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageHisteqButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tugas1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end